classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MaxHeightAchievedmEditField     matlab.ui.control.NumericEditField
        EditField3Label                 matlab.ui.control.Label
        LaunchAngledegEditField         matlab.ui.control.NumericEditField
        LaunchAngledegEditFieldLabel    matlab.ui.control.Label
        MinimumVelocitymsEditField      matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        OptimizedResultsLabel           matlab.ui.control.Label
        RunSimulationButton             matlab.ui.control.Button
        BuildingHeightHmEditField       matlab.ui.control.NumericEditField
        BuildingHeightHmEditFieldLabel  matlab.ui.control.Label
        DistancetoWallDmEditField       matlab.ui.control.NumericEditField
        DistancetoWallDmEditFieldLabel  matlab.ui.control.Label
        ObstacleParametersLabel         matlab.ui.control.Label
        InitialLaunchCoordinatesALabel  matlab.ui.control.Label
        z0mEditField                    matlab.ui.control.NumericEditField
        z0mEditFieldLabel               matlab.ui.control.Label
        y0mEditField                    matlab.ui.control.NumericEditField
        y0mEditFieldLabel               matlab.ui.control.Label
        x0mEditField                    matlab.ui.control.NumericEditField
        x0mEditFieldLabel               matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            x0=app.x0mEditField.Value;
            y0=app.y0mEditField.Value;
            z0=app.z0mEditField.Value;
            D=app.DistancetoWallDmEditField.Value;
            H=app.BuildingHeightHmEditField.Value;

            g = 9.81;
            xB = x0 + D + 10; 
            yB = 5;           
            x_total = xB - x0;

            alpha_range = 1:1:89; % Tests every single integer angle from 1 to 89
            best_V = Inf;
            best_alpha = NaN;

            for i = 1:length(alpha_range)
                alpha_rad = deg2rad(alpha_range(i));

                % Equation for the trajectory of a projectile motion
                num = g * (x_total^2);
                den = 2 * (cos(alpha_rad)^2) * (y0 + x_total*tan(alpha_rad) - yB);

                if den > 0
                    V_req = sqrt(num / den);

                    % Check height at the obstacle wall position
                    t_build = D / (V_req * cos(alpha_rad));
                    y_build = y0 + V_req * sin(alpha_rad) * t_build - 0.5 * g * (t_build^2);

                    % Must clear the building by at least 2 meters
                    if y_build >= (H + 2)
                        if V_req < best_V
                            best_V = V_req;
                            best_alpha = alpha_range(i);
                        end
                    end
                end
            end

            % Generates the path and plot if a solution exists
            if isfinite(best_V)
                alpha_final = deg2rad(best_alpha);
                T_flight = x_total / (best_V * cos(alpha_final));
                t_span = linspace(0, T_flight, 200);

                % Trajectory paths
                x_path = x0 + best_V * cos(alpha_final) * t_span;
                y_path = y0 + best_V * sin(alpha_final) * t_span - 0.5 * g * (t_span.^2);

                % Sending numeric answers back to output boxes
                app.MinimumVelocitymsEditField.Value = best_V;
                app.LaunchAngledegEditField.Value = best_alpha;
                app.MaxHeightAchievedmEditField.Value = max(y_path);

                % Plotting all 3 visuals
                hold(app.UIAxes, 'off');
                plot(app.UIAxes, x_path, y_path, 'b');               % Trajectory path line
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, [x0 + D, x0 + D], [0, H], 'r');     % Obstacle wall line
                plot(app.UIAxes, xB, 5, 'g*');                       % Destination point
                
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Projectile Trajectory')
            xlabel(app.UIAxes, 'Horizontal Distance (m)')
            ylabel(app.UIAxes, 'Vertical Height (m)')
            zlabel(app.UIAxes, 'Z')
            app.x0mEditFieldLabel = uilabel(app.UIFigure);
            app.x0mEditFieldLabel.Text = 'x0 (m)';
            app.x0mEditField = uieditfield(app.UIFigure, 'numeric');
            app.y0mEditFieldLabel = uilabel(app.UIFigure);
            app.y0mEditFieldLabel.Text = 'y0 (m)';
            app.y0mEditField = uieditfield(app.UIFigure, 'numeric');
            app.z0mEditFieldLabel = uilabel(app.UIFigure);
            app.z0mEditFieldLabel.Text = 'z0 (m)';
            app.z0mEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialLaunchCoordinatesALabel = uilabel(app.UIFigure);
            app.InitialLaunchCoordinatesALabel.Text = 'Initial Launch Coordinates (A)';
            app.ObstacleParametersLabel = uilabel(app.UIFigure);
            app.ObstacleParametersLabel.Text = 'Obstacle Parameters';
            app.DistancetoWallDmEditFieldLabel = uilabel(app.UIFigure);
            app.DistancetoWallDmEditFieldLabel.Text = 'Distance to Wall D (m)';
            app.DistancetoWallDmEditField = uieditfield(app.UIFigure, 'numeric');
            app.BuildingHeightHmEditFieldLabel = uilabel(app.UIFigure);
            app.BuildingHeightHmEditFieldLabel.Text = 'Building Height H (m)';
            app.BuildingHeightHmEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.OptimizedResultsLabel = uilabel(app.UIFigure);
            app.OptimizedResultsLabel.Text = 'Optimized Results';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Minimum Velocity (m/s)';
            app.MinimumVelocitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumVelocitymsEditField.Editable = 'off';
            app.LaunchAngledegEditFieldLabel = uilabel(app.UIFigure);
            app.LaunchAngledegEditFieldLabel.Text = 'Launch Angle (deg)';
            app.LaunchAngledegEditField = uieditfield(app.UIFigure, 'numeric');
            app.LaunchAngledegEditField.Editable = 'off';
            app.EditField3Label = uilabel(app.UIFigure);
            app.EditField3Label.Text = 'Max Height Achieved (m)';
            app.MaxHeightAchievedmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxHeightAchievedmEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end